classdef TSADesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DesignOptimizationofTwistedStringActuatorsLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        AssumptionsPanel                matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        PayloadMass                     matlab.ui.control.NumericEditField
        PayloadMasskgEditFieldLabel     matlab.ui.control.Label
        c                               matlab.ui.control.NumericEditField
        MaximumLinearStrainLabel        matlab.ui.control.Label
        MotorTimeConstant               matlab.ui.control.NumericEditField
        MotorTimeConstantsLabel         matlab.ui.control.Label
        MotorCoulombicFriction          matlab.ui.control.NumericEditField
        MotorCoulombicFrictionNmEditFieldLabel  matlab.ui.control.Label
        PayloadCoulombicFriction        matlab.ui.control.NumericEditField
        PayloadCoulombicFrictionNEditFieldLabel  matlab.ui.control.Label
        MotorInertia                    matlab.ui.control.NumericEditField
        MotorMomentofInertiakgm2EditFieldLabel  matlab.ui.control.Label
        RadialStringStiffness           matlab.ui.control.NumericEditField
        RadialStringStiffnessNmEditFieldLabel  matlab.ui.control.Label
        PayloadViscousFriction          matlab.ui.control.NumericEditField
        PayloadViscousFrictionNsmEditFieldLabel  matlab.ui.control.Label
        MotorViscousFriction            matlab.ui.control.NumericEditField
        MotorViscousFrictionNmsLabel    matlab.ui.control.Label
        Step2ConstraintsOptionalPanel   matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        MaximumVolumemm3Label           matlab.ui.control.Label
        ComputeButton                   matlab.ui.control.Button
        voltage                         matlab.ui.control.DropDown
        nominalVoltageLabel             matlab.ui.control.Label
        MaximumMassgLabel               matlab.ui.control.Label
        maxMass                         matlab.ui.control.NumericEditField
        IdealVolumemm3Label             matlab.ui.control.Label
        maxVolume                       matlab.ui.control.NumericEditField
        minVolume                       matlab.ui.control.NumericEditField
        maxPrice                        matlab.ui.control.NumericEditField
        MaximumPriceUSDLabel            matlab.ui.control.Label
        Step3SolutiontotheInverseModelPanel  matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        computationTime                 matlab.ui.control.NumericEditField
        TimetoconvergencesEditFieldLabel  matlab.ui.control.Label
        iterations                      matlab.ui.control.NumericEditField
        IterationstoconvergenceEditFieldLabel  matlab.ui.control.Label
        K                               matlab.ui.control.NumericEditField
        StringStiffnessNmEditFieldLabel  matlab.ui.control.Label
        PeakTorque                      matlab.ui.control.NumericEditField
        PeakpowerrequirementWLabel_2    matlab.ui.control.Label
        MaxRotations                    matlab.ui.control.NumericEditField
        MaximumRotationsLabel           matlab.ui.control.Label
        X0                              matlab.ui.control.NumericEditField
        LoadedStringLengthcmLabel       matlab.ui.control.Label
        L0                              matlab.ui.control.NumericEditField
        UnloadedStringLengthcmLabel     matlab.ui.control.Label
        peakPower                       matlab.ui.control.NumericEditField
        PeakpowerrequirementWLabel      matlab.ui.control.Label
        dX_actual                       matlab.ui.control.NumericEditField
        SimulatedcontractionrangemLabel  matlab.ui.control.Label
        dt_actual                       matlab.ui.control.NumericEditField
        SimulatedcontractiontimesLabel  matlab.ui.control.Label
        StallTorque                     matlab.ui.control.NumericEditField
        StallTorquekgcmEditFieldLabel   matlab.ui.control.Label
        NoLoadSpeed                     matlab.ui.control.NumericEditField
        NoLoadSpeedRPMEditFieldLabel    matlab.ui.control.Label
        ComputationalPerformanceLabel   matlab.ui.control.Label
        MotorPropertiesLabel            matlab.ui.control.Label
        DynamicModelLabel               matlab.ui.control.Label
        StaticModelLabel                matlab.ui.control.Label
        Step4RecommendedMotorsPanel     matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        suitableMotorMessage            matlab.ui.control.Label
        motorType5                      matlab.ui.control.EditField
        motorType4                      matlab.ui.control.EditField
        motorType3                      matlab.ui.control.EditField
        motorType2                      matlab.ui.control.EditField
        motorType1                      matlab.ui.control.EditField
        motorTypeLabel                  matlab.ui.control.Label
        motorVoltage5                   matlab.ui.control.NumericEditField
        motorVoltage4                   matlab.ui.control.NumericEditField
        motorVoltage3                   matlab.ui.control.NumericEditField
        motorVoltage2                   matlab.ui.control.NumericEditField
        motorVoltage1                   matlab.ui.control.NumericEditField
        VoltageLabel                    matlab.ui.control.Label
        motorPrice5                     matlab.ui.control.EditField
        motorPrice4                     matlab.ui.control.EditField
        motorPrice3                     matlab.ui.control.EditField
        motorPrice2                     matlab.ui.control.EditField
        PriceLabel                      matlab.ui.control.Label
        motorPrice1                     matlab.ui.control.EditField
        motorVolume5                    matlab.ui.control.EditField
        motorVolume4                    matlab.ui.control.EditField
        motorVolume3                    matlab.ui.control.EditField
        motorVolume2                    matlab.ui.control.EditField
        motorVolume1                    matlab.ui.control.EditField
        BoxedVolumeLabel                matlab.ui.control.Label
        motorMass5                      matlab.ui.control.EditField
        motorMass4                      matlab.ui.control.EditField
        motorMass3                      matlab.ui.control.EditField
        motorMass2                      matlab.ui.control.EditField
        motorMass1                      matlab.ui.control.EditField
        MassLabel                       matlab.ui.control.Label
        motorSpeed5                     matlab.ui.control.EditField
        motorSpeed4                     matlab.ui.control.EditField
        motorSpeed3                     matlab.ui.control.EditField
        motorSpeed2                     matlab.ui.control.EditField
        motorSpeed1                     matlab.ui.control.EditField
        FreeRunSpeedLabel               matlab.ui.control.Label
        motorStallTorque5               matlab.ui.control.EditField
        motorStallTorque4               matlab.ui.control.EditField
        motorStallTorque3               matlab.ui.control.EditField
        motorStallTorque2               matlab.ui.control.EditField
        motorStallTorque1               matlab.ui.control.EditField
        StallTorqueLabel                matlab.ui.control.Label
        motorModel5                     matlab.ui.control.EditField
        motorModel4                     matlab.ui.control.EditField
        motorModel3                     matlab.ui.control.EditField
        motorModel2                     matlab.ui.control.EditField
        ModelNumberLabel                matlab.ui.control.Label
        motorModel1                     matlab.ui.control.EditField
        motor5Label                     matlab.ui.control.Label
        motor4Label                     matlab.ui.control.Label
        motor3Label                     matlab.ui.control.Label
        motor1Label                     matlab.ui.control.Label
        motor2Label                     matlab.ui.control.Label
        motorDistance5                  matlab.ui.control.NumericEditField
        motorDistance4                  matlab.ui.control.NumericEditField
        motorDistance3                  matlab.ui.control.NumericEditField
        motorDistance2                  matlab.ui.control.NumericEditField
        motorDistance1                  matlab.ui.control.NumericEditField
        DistanceLabel                   matlab.ui.control.Label
        VendorLabel                     matlab.ui.control.Label
        motorVendor5                    matlab.ui.control.EditField
        motorVendor4                    matlab.ui.control.EditField
        motorVendor3                    matlab.ui.control.EditField
        motorVendor1                    matlab.ui.control.EditField
        motorVendor2                    matlab.ui.control.EditField
        Step1DesiredPerformancePanel    matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        Fz_min                          matlab.ui.control.NumericEditField
        MinimumaxialforceNEditFieldLabel  matlab.ui.control.Label
        StringRadius                    matlab.ui.control.NumericEditField
        StringStiffnessNmLabel_2        matlab.ui.control.Label
        ForceProfile                    matlab.ui.control.DropDown
        AxialforceprofileNDropDownLabel  matlab.ui.control.Label
        dX                              matlab.ui.control.NumericEditField
        ContractionrangemLabel          matlab.ui.control.Label
        Si                              matlab.ui.control.NumericEditField
        StringStiffnessNmLabel          matlab.ui.control.Label
        dt                              matlab.ui.control.NumericEditField
        TimetofullycontractsEditFieldLabel  matlab.ui.control.Label
        Fz_max                          matlab.ui.control.NumericEditField
        MaximumaxialforceNLabel         matlab.ui.control.Label
    end


    methods (Access = private)

        
    end
    
    methods (Access = public)
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear; clc;
            %app.Fz_max.Limits = [app.Fz_min.Value, app.Fz_min.Value + 10];
            %app.MotorTimeConstant.Limits = [0, app.dt.Value*0.5];
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            % Solve the Static Model
            N = 2;
            Fz0 = app.Fz_min.Value;
            Fz_max_ = app.Fz_max.Value;
            c_ = app.c.Value;
            Si_ = app.Si.Value;
            dt_ = app.dt.Value;
            dX_ = app.dX.Value;
            [L0_,X0_,K_] = dX2X0(dX_,Fz0,Si_,N,c_/100);
            app.X0.Value = X0_;
            app.K.Value = K_;
            app.L0.Value = L0_;

            %%%%%%%%%%% Assumptions
            bX_ = app.PayloadViscousFriction.Value;
            bO_ = app.MotorViscousFriction.Value;
            Kr_ = app.RadialStringStiffness.Value;
            J_ = app.MotorInertia.Value;
            Fc_ = app.PayloadCoulombicFriction.Value;
            Tc_ = app.MotorCoulombicFriction.Value;
            M_ = app.PayloadMass.Value;
            dynamic_constants_ = [bX_, bO_, Kr_, J_, Fc_, Tc_, M_];
            %%%%%%%%%%%
            if strcmp(app.ForceProfile.Value, 'Linear')
                FP = 1;
            elseif strcmp(app.ForceProfile.Value,'Parabolic')
                FP = 2;
            elseif strcmp(app.ForceProfile.Value,'Randomly Varying')
                FP = 3;
            end
            R0 = app.StringRadius.Value/1000;
            tau_ = app.MotorTimeConstant.Value;
            [dOdt, Tm, dt_actual_, P, s, Xdiff, tEnd,O_] = ...
                coupledProps3(R0,Fz0,Fz_max_,FP,N,X0_,L0_,K_,dt_,dX_,200*dX_,tau_,dynamic_constants_);
            %fprintf('Xdiff = %.5f \n', Xdiff)
            app.MaxRotations.Value = max(O_)/2/pi;
            app.dX_actual.Value = Xdiff(end) + dX_;
            app.dt_actual.Value = dt_actual_;
            T = max(Tm)*5; % the stall torque should not exceed 20% of the torque the
            % TSA will require (conservative estimate since it's using the max torque)
            app.peakPower.Value = max(P);
            app.PeakTorque.Value = max(Tm);
            % compute free-run speed based on the actual speed and stall torque
            W1 = (max(dOdt)/(max(Tm) - T))*(0 - T) + 0;
            W1 = W1*60/2/pi; 
            T = T*10.197162129779;
            app.NoLoadSpeed.Value = W1; % convert rad/s to RPM
            app.StallTorque.Value = T; % convert (N*m) to (kg*cm);
            app.computationTime.Value = tEnd;
            app.iterations.Value = length(s);
            if strcmp(app.voltage.Value,'Any')
                V_ = 0;
            else
                V_ = str2double(app.voltage.Value);
            end
                minVolume_ = app.minVolume.Value;
                maxVolume_ = app.maxVolume.Value;
            maxMass_ = app.maxMass.Value;
            maxPrice_ = app.maxPrice.Value;
            [item_final, vendor, act_torque, T_us, RPM_us, distance,act_vol,act_mass,act_NLRPM,act_price,act_voltage,act_type] = ...
            euclid_dist_fnctn2(W1,T,minVolume_,maxVolume_,V_,maxPrice_,maxMass_);
            %[item_final, vendor,act_torque, torque, RPM, dist] = ...
            %euclid_dist_fnctn2(1846,1.7149,5000,Inf,12);
            for i = 1:length(item_final)
                if isnumeric(item_final{i})
                    item_final{i} = string(item_final{i});
                end
            end
            if ~isfinite(distance)
                app.suitableMotorMessage.Visible = 'on';
                app.motorTypeLabel.Visible = 'off';
                app.VoltageLabel.Visible = 'off';
                app.PriceLabel.Visible = 'off';
                app.BoxedVolumeLabel.Visible = 'off';
                app.MassLabel.Visible = 'off';
                app.FreeRunSpeedLabel.Visible = 'off';
                app.StallTorqueLabel.Visible = 'off';
                app.ModelNumberLabel.Visible = 'off';
                app.DistanceLabel.Visible = 'off';
                app.VendorLabel.Visible = 'off';
                app.motorVendor1.Visible = 'off';
                app.motorModel1.Visible = 'off';
                app.motor1Label.Visible = 'off';
                app.motorStallTorque1.Visible = 'off';
                app.motorSpeed1.Visible = 'off';
                app.motorMass1.Visible = 'off';
                app.motorVolume1.Visible = 'off';
                app.motorPrice1.Visible = 'off';
                app.motorDistance1.Visible = 'off';
                app.motorVoltage1.Visible = 'off';
                app.motorType1.Visible = 'off';
                app.motorVendor2.Visible = 'off';
                app.motorModel2.Visible = 'off';
                app.motor2Label.Visible = 'off';
                app.motorStallTorque2.Visible = 'off';
                app.motorSpeed2.Visible = 'off';
                app.motorMass2.Visible = 'off';
                app.motorVolume2.Visible = 'off';
                app.motorDistance2.Visible = 'off';
                app.motorPrice2.Visible = 'off';
                app.motorVoltage2.Visible = 'off';
                app.motorType2.Visible = 'off';
                app.motorVendor3.Visible = 'off';
                app.motorModel3.Visible = 'off';
                app.motor3Label.Visible = 'off';
                app.motorStallTorque3.Visible = 'off';
                app.motorSpeed3.Visible = 'off';
                app.motorMass3.Visible = 'off';
                app.motorVolume3.Visible = 'off';
                app.motorDistance3.Visible = 'off';
                app.motorPrice3.Visible = 'off';
                app.motorVoltage3.Visible = 'off';
                app.motorType3.Visible = 'off';
                app.motorVendor4.Visible = 'off';
                app.motorModel4.Visible = 'off';
                app.motor4Label.Visible = 'off';
                app.motorStallTorque4.Visible = 'off';
                app.motorSpeed4.Visible = 'off';
                app.motorMass4.Visible = 'off';
                app.motorVolume4.Visible = 'off';
                app.motorDistance4.Visible = 'off';
                app.motorPrice4.Visible = 'off';
                app.motorVoltage4.Visible = 'off';
                app.motorType4.Visible = 'off';
                app.motorVendor5.Visible = 'off';
                app.motorModel5.Visible = 'off';
                app.motor5Label.Visible = 'off';
                app.motorStallTorque5.Visible = 'off';
                app.motorSpeed5.Visible = 'off';
                app.motorMass5.Visible = 'off';
                app.motorVolume5.Visible = 'off';
                app.motorDistance5.Visible = 'off';
                app.motorPrice5.Visible = 'off';
                app.motorVoltage5.Visible = 'off';
                app.motorType5.Visible = 'off';
            else
            if length(distance) >= 1
                app.suitableMotorMessage.Visible = 'off';
                app.motorTypeLabel.Visible = 'on';
                app.VoltageLabel.Visible = 'on';
                app.PriceLabel.Visible = 'on';
                app.BoxedVolumeLabel.Visible = 'on';
                app.MassLabel.Visible = 'on';
                app.FreeRunSpeedLabel.Visible = 'on';
                app.StallTorqueLabel.Visible = 'on';
                app.ModelNumberLabel.Visible = 'on';
                app.DistanceLabel.Visible = 'on';
                app.VendorLabel.Visible = 'on';
                app.motorVendor1.Visible = 'on';
                app.motorModel1.Visible = 'on';
                app.motor1Label.Visible = 'on';
                app.motorStallTorque1.Visible = 'on';
                app.motorSpeed1.Visible = 'on';
                app.motorMass1.Visible = 'on';
                app.motorVolume1.Visible = 'on';
                app.motorPrice1.Visible = 'on';
                app.motorDistance1.Visible = 'on';
                app.motorVoltage1.Visible = 'on';
                app.motorType1.Visible = 'on';
                app.motorVendor2.Visible = 'off';
                app.motorModel2.Visible = 'off';
                app.motor2Label.Visible = 'off';
                app.motorStallTorque2.Visible = 'off';
                app.motorSpeed2.Visible = 'off';
                app.motorMass2.Visible = 'off';
                app.motorVolume2.Visible = 'off';
                app.motorDistance2.Visible = 'off';
                app.motorPrice2.Visible = 'off';
                app.motorVoltage2.Visible = 'off';
                app.motorType2.Visible = 'off';
                app.motorVendor3.Visible = 'off';
                app.motorModel3.Visible = 'off';
                app.motor3Label.Visible = 'off';
                app.motorStallTorque3.Visible = 'off';
                app.motorSpeed3.Visible = 'off';
                app.motorMass3.Visible = 'off';
                app.motorVolume3.Visible = 'off';
                app.motorDistance3.Visible = 'off';
                app.motorPrice3.Visible = 'off';
                app.motorVoltage3.Visible = 'off';
                app.motorType3.Visible = 'off';
                app.motorVendor4.Visible = 'off';
                app.motorModel4.Visible = 'off';
                app.motor4Label.Visible = 'off';
                app.motorStallTorque4.Visible = 'off';
                app.motorSpeed4.Visible = 'off';
                app.motorMass4.Visible = 'off';
                app.motorVolume4.Visible = 'off';
                app.motorDistance4.Visible = 'off';
                app.motorPrice4.Visible = 'off';
                app.motorVoltage4.Visible = 'off';
                app.motorType4.Visible = 'off';
                app.motorVendor5.Visible = 'off';
                app.motorModel5.Visible = 'off';
                app.motor5Label.Visible = 'off';
                app.motorStallTorque5.Visible = 'off';
                app.motorSpeed5.Visible = 'off';
                app.motorMass5.Visible = 'off';
                app.motorVolume5.Visible = 'off';
                app.motorDistance5.Visible = 'off';
                app.motorPrice5.Visible = 'off';
                app.motorVoltage5.Visible = 'off';
                app.motorType5.Visible = 'off';
                app.motorVendor1.Value = vendor{1};
                app.motorModel1.Value = item_final{1};
                app.motorStallTorque1.Value = string(act_torque{1});
                app.motorSpeed1.Value =  string(act_NLRPM{1});
                app.motorMass1.Value =  string(act_mass{1});
                app.motorVolume1.Value =  string(act_vol{1});
                app.motorDistance1.Value =  (distance(1));
                app.motorPrice1.Value =  string(act_price{1});
                app.motorVoltage1.Value = act_voltage{1};
                app.motorType1.Value = act_type{1};

            end
            if length(distance) >= 2
                app.motorVendor2.Visible = 'on';
                app.motorModel2.Visible = 'on';
                app.motor2Label.Visible = 'on';
                app.motorStallTorque2.Visible = 'on';
                app.motorSpeed2.Visible = 'on';
                app.motorMass2.Visible = 'on';
                app.motorVolume2.Visible = 'on';
                app.motorDistance2.Visible = 'on';
                app.motorPrice2.Visible = 'on';
                app.motorVoltage2.Visible = 'on';
                app.motorType2.Visible = 'on';
                app.motorVendor3.Visible = 'off';
                app.motorModel3.Visible = 'off';
                app.motor3Label.Visible = 'off';
                app.motorStallTorque3.Visible = 'off';
                app.motorSpeed3.Visible = 'off';
                app.motorMass3.Visible = 'off';
                app.motorVolume3.Visible = 'off';
                app.motorDistance3.Visible = 'off';
                app.motorPrice3.Visible = 'off';
                app.motorVoltage3.Visible = 'off';
                app.motorType3.Visible = 'off';
                app.motorVendor4.Visible = 'off';
                app.motorModel4.Visible = 'off';
                app.motor4Label.Visible = 'off';
                app.motorStallTorque4.Visible = 'off';
                app.motorSpeed4.Visible = 'off';
                app.motorMass4.Visible = 'off';
                app.motorVolume4.Visible = 'off';
                app.motorDistance4.Visible = 'off';
                app.motorPrice4.Visible = 'off';
                app.motorVoltage4.Visible = 'off';
                app.motorType4.Visible = 'off';
                app.motorVendor5.Visible = 'off';
                app.motorModel5.Visible = 'off';
                app.motor5Label.Visible = 'off';
                app.motorStallTorque5.Visible = 'off';
                app.motorSpeed5.Visible = 'off';
                app.motorMass5.Visible = 'off';
                app.motorVolume5.Visible = 'off';
                app.motorDistance5.Visible = 'off';
                app.motorPrice5.Visible = 'off';
                app.motorVoltage5.Visible = 'off';
                app.motorType5.Visible = 'off';
                app.motorVendor2.Value = vendor{2};
                app.motorModel2.Value = item_final{2};
                app.motorStallTorque2.Value =  string(act_torque{2});
                app.motorSpeed2.Value =  string(act_NLRPM{2});
                app.motorMass2.Value =  string(act_mass{2});
                app.motorVolume2.Value =  string(act_vol{2});
                app.motorDistance2.Value =  (distance(2));
                app.motorPrice2.Value =  string(act_price{2});
                app.motorVoltage2.Value = act_voltage{2};
                app.motorType2.Value = act_type{2};
            end
            if length(distance)>= 3
                app.motorVendor3.Visible = 'on';
                app.motorModel3.Visible = 'on';
                app.motor3Label.Visible = 'on';
                app.motorStallTorque3.Visible = 'on';
                app.motorSpeed3.Visible = 'on';
                app.motorMass3.Visible = 'on';
                app.motorVolume3.Visible = 'on';
                app.motorDistance3.Visible = 'on';
                app.motorPrice3.Visible = 'on';
                app.motorVoltage3.Visible = 'on';
                app.motorType3.Visible = 'on';
                app.motorVendor4.Visible = 'off';
                app.motorModel4.Visible = 'off';
                app.motor4Label.Visible = 'off';
                app.motorStallTorque4.Visible = 'off';
                app.motorSpeed4.Visible = 'off';
                app.motorMass4.Visible = 'off';
                app.motorVolume4.Visible = 'off';
                app.motorDistance4.Visible = 'off';
                app.motorPrice4.Visible = 'off';
                app.motorVoltage4.Visible = 'off';
                app.motorType4.Visible = 'off';
                app.motorVendor5.Visible = 'off';
                app.motorModel5.Visible = 'off';
                app.motor5Label.Visible = 'off';
                app.motorStallTorque5.Visible = 'off';
                app.motorSpeed5.Visible = 'off';
                app.motorMass5.Visible = 'off';
                app.motorVolume5.Visible = 'off';
                app.motorDistance5.Visible = 'off';
                app.motorPrice5.Visible = 'off';
                app.motorVoltage5.Visible = 'off';
                app.motorType5.Visible = 'off';
                app.motorVendor3.Value = vendor{3};
                app.motorModel3.Value = item_final{3};
                app.motorStallTorque3.Value =  string(act_torque{3});
                app.motorSpeed3.Value =  string(act_NLRPM{3});
                app.motorMass3.Value =  string(act_mass{3});
                app.motorVolume3.Value =  string(act_vol{3});
                app.motorDistance3.Value =  (distance(3));
                app.motorPrice3.Value =  string(act_price{3});
                app.motorVoltage3.Value = act_voltage{3};
                app.motorType3.Value = act_type{3};
            end
            if length(distance)>= 4
                app.motorVendor4.Visible = 'on';
                app.motorModel4.Visible = 'on';
                app.motor4Label.Visible = 'on';
                app.motorStallTorque4.Visible = 'on';
                app.motorSpeed4.Visible = 'on';
                app.motorMass4.Visible = 'on';
                app.motorVolume4.Visible = 'on';
                app.motorDistance4.Visible = 'on';
                app.motorPrice4.Visible = 'on';
                app.motorVoltage4.Visible = 'on';
                app.motorType4.Visible = 'on';
                app.motorVendor5.Visible = 'off';
                app.motorModel5.Visible = 'off';
                app.motor5Label.Visible = 'off';
                app.motorStallTorque5.Visible = 'off';
                app.motorSpeed5.Visible = 'off';
                app.motorMass5.Visible = 'off';
                app.motorVolume5.Visible = 'off';
                app.motorDistance5.Visible = 'off';
                app.motorPrice5.Visible = 'off';
                app.motorVoltage5.Visible = 'off';
                app.motorType5.Visible = 'off';
                app.motorVendor4.Value = vendor{4};
                app.motorModel4.Value = item_final{4};
                app.motorStallTorque4.Value =  string(act_torque{4});
                app.motorSpeed4.Value =  string(act_NLRPM{4});
                app.motorMass4.Value =  string(act_mass{4});
                app.motorVolume4.Value =  string(act_vol{4});
                app.motorDistance4.Value =  (distance(4));
                app.motorPrice4.Value =  string(act_price{4});
                app.motorVoltage4.Value = act_voltage{4};
                app.motorType4.Value = act_type{4};
            end
            if length(distance)>= 5
                app.motorVendor5.Visible = 'on';
                app.motorModel5.Visible = 'on';
                app.motor5Label.Visible = 'on';
                app.motorStallTorque5.Visible = 'on';
                app.motorSpeed5.Visible = 'on';
                app.motorMass5.Visible = 'on';
                app.motorVolume5.Visible = 'on';
                app.motorDistance5.Visible = 'on';
                app.motorPrice5.Visible = 'on';
                app.motorVoltage5.Visible = 'on';
                app.motorType5.Visible = 'on';
                app.motorVendor5.Value = string(vendor{5});
                app.motorModel5.Value = item_final{5};
                app.motorStallTorque5.Value =  string(act_torque{5});
                app.motorSpeed5.Value =  string(act_NLRPM{5});
                app.motorMass5.Value =  string(act_mass{5});
                app.motorVolume5.Value =  string(act_vol{5});
                app.motorDistance5.Value = (distance(5));
                app.motorPrice5.Value =  string(act_price{5});
                app.motorVoltage5.Value = act_voltage{5};
                app.motorType5.Value = act_type{5};
            end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.Step1DesiredPerformancePanel = uipanel(app.HomeTab);
            app.Step1DesiredPerformancePanel.Title = 'Step 1: Desired Performance';
            app.GridLayout2 = uigridlayout(app.Step1DesiredPerformancePanel);
            app.MaximumaxialforceNLabel = uilabel(app.GridLayout2);
            app.MaximumaxialforceNLabel.Text = 'Maximum axial force (N)';
            app.Fz_max = uieditfield(app.GridLayout2, 'numeric');
            app.Fz_max.Value = 20;
            app.TimetofullycontractsEditFieldLabel = uilabel(app.GridLayout2);
            app.TimetofullycontractsEditFieldLabel.Text = 'Time to fully contract (s)';
            app.dt = uieditfield(app.GridLayout2, 'numeric');
            app.dt.Limits = [0.2 10];
            app.dt.Value = 2;
            app.StringStiffnessNmLabel = uilabel(app.GridLayout2);
            app.StringStiffnessNmLabel.Text = 'Initial TSA Stiffness (N/m)';
            app.Si = uieditfield(app.GridLayout2, 'numeric');
            app.Si.Limits = [100 1000];
            app.Si.Value = 500;
            app.ContractionrangemLabel = uilabel(app.GridLayout2);
            app.ContractionrangemLabel.Text = 'Contraction range (m)';
            app.dX = uieditfield(app.GridLayout2, 'numeric');
            app.dX.Limits = [0.1 0.3];
            app.dX.Value = 0.1;
            app.AxialforceprofileNDropDownLabel = uilabel(app.GridLayout2);
            app.AxialforceprofileNDropDownLabel.Text = 'Axial force profile (N)';
            app.ForceProfile = uidropdown(app.GridLayout2);
            app.ForceProfile.Items = {'Linear', 'Parabolic', 'Randomly Varying'};
            app.ForceProfile.Value = 'Linear';
            app.StringStiffnessNmLabel_2 = uilabel(app.GridLayout2);
            app.StringStiffnessNmLabel_2.Text = 'String Radius (mm)';
            app.StringRadius = uieditfield(app.GridLayout2, 'numeric');
            app.StringRadius.Value = 1;
            app.MinimumaxialforceNEditFieldLabel = uilabel(app.GridLayout2);
            app.MinimumaxialforceNEditFieldLabel.Text = 'Minimum axial force (N)';
            app.Fz_min = uieditfield(app.GridLayout2, 'numeric');
            app.Fz_min.Limits = [0 20];
            app.Fz_min.Value = 10;
            app.Step4RecommendedMotorsPanel = uipanel(app.HomeTab);
            app.Step4RecommendedMotorsPanel.Title = 'Step 4: Recommended Motors';
            app.GridLayout = uigridlayout(app.Step4RecommendedMotorsPanel);
            app.motorVendor2 = uieditfield(app.GridLayout, 'text');
            app.motorVendor2.Editable = 'off';
            app.motorVendor2.Visible = 'off';
            app.motorVendor1 = uieditfield(app.GridLayout, 'text');
            app.motorVendor1.Editable = 'off';
            app.motorVendor1.Visible = 'off';
            app.motorVendor3 = uieditfield(app.GridLayout, 'text');
            app.motorVendor3.Editable = 'off';
            app.motorVendor3.Visible = 'off';
            app.motorVendor4 = uieditfield(app.GridLayout, 'text');
            app.motorVendor4.Editable = 'off';
            app.motorVendor4.Visible = 'off';
            app.motorVendor5 = uieditfield(app.GridLayout, 'text');
            app.motorVendor5.Editable = 'off';
            app.motorVendor5.Visible = 'off';
            app.VendorLabel = uilabel(app.GridLayout);
            app.VendorLabel.Visible = 'off';
            app.VendorLabel.Text = 'Vendor';
            app.DistanceLabel = uilabel(app.GridLayout);
            app.DistanceLabel.Visible = 'off';
            app.DistanceLabel.Text = 'Distance';
            app.motorDistance1 = uieditfield(app.GridLayout, 'numeric');
            app.motorDistance1.Editable = 'off';
            app.motorDistance1.Visible = 'off';
            app.motorDistance2 = uieditfield(app.GridLayout, 'numeric');
            app.motorDistance2.Editable = 'off';
            app.motorDistance2.Visible = 'off';
            app.motorDistance3 = uieditfield(app.GridLayout, 'numeric');
            app.motorDistance3.Editable = 'off';
            app.motorDistance3.Visible = 'off';
            app.motorDistance4 = uieditfield(app.GridLayout, 'numeric');
            app.motorDistance4.Editable = 'off';
            app.motorDistance4.Visible = 'off';
            app.motorDistance5 = uieditfield(app.GridLayout, 'numeric');
            app.motorDistance5.Editable = 'off';
            app.motorDistance5.Visible = 'off';
            app.motor2Label = uilabel(app.GridLayout);
            app.motor2Label.Visible = 'off';
            app.motor2Label.Text = '2';
            app.motor1Label = uilabel(app.GridLayout);
            app.motor1Label.Visible = 'off';
            app.motor1Label.Text = '1';
            app.motor3Label = uilabel(app.GridLayout);
            app.motor3Label.Visible = 'off';
            app.motor3Label.Text = '3';
            app.motor4Label = uilabel(app.GridLayout);
            app.motor4Label.Visible = 'off';
            app.motor4Label.Text = '4';
            app.motor5Label = uilabel(app.GridLayout);
            app.motor5Label.Visible = 'off';
            app.motor5Label.Text = '5';
            app.motorModel1 = uieditfield(app.GridLayout, 'text');
            app.motorModel1.Editable = 'off';
            app.motorModel1.Visible = 'off';
            app.ModelNumberLabel = uilabel(app.GridLayout);
            app.ModelNumberLabel.Visible = 'off';
            app.ModelNumberLabel.Text = 'Model Number';
            app.motorModel2 = uieditfield(app.GridLayout, 'text');
            app.motorModel2.Visible = 'off';
            app.motorModel3 = uieditfield(app.GridLayout, 'text');
            app.motorModel3.Visible = 'off';
            app.motorModel4 = uieditfield(app.GridLayout, 'text');
            app.motorModel4.Visible = 'off';
            app.motorModel5 = uieditfield(app.GridLayout, 'text');
            app.motorModel5.Visible = 'off';
            app.StallTorqueLabel = uilabel(app.GridLayout);
            app.StallTorqueLabel.Visible = 'off';
            app.StallTorqueLabel.Text = 'Stall Torque';
            app.motorStallTorque1 = uieditfield(app.GridLayout, 'text');
            app.motorStallTorque1.Editable = 'off';
            app.motorStallTorque1.Visible = 'off';
            app.motorStallTorque2 = uieditfield(app.GridLayout, 'text');
            app.motorStallTorque2.Visible = 'off';
            app.motorStallTorque3 = uieditfield(app.GridLayout, 'text');
            app.motorStallTorque3.Visible = 'off';
            app.motorStallTorque4 = uieditfield(app.GridLayout, 'text');
            app.motorStallTorque4.Visible = 'off';
            app.motorStallTorque5 = uieditfield(app.GridLayout, 'text');
            app.motorStallTorque5.Visible = 'off';
            app.FreeRunSpeedLabel = uilabel(app.GridLayout);
            app.FreeRunSpeedLabel.Visible = 'off';
            app.FreeRunSpeedLabel.Text = 'Free-Run Speed';
            app.motorSpeed1 = uieditfield(app.GridLayout, 'text');
            app.motorSpeed1.Editable = 'off';
            app.motorSpeed1.Visible = 'off';
            app.motorSpeed2 = uieditfield(app.GridLayout, 'text');
            app.motorSpeed2.Visible = 'off';
            app.motorSpeed3 = uieditfield(app.GridLayout, 'text');
            app.motorSpeed3.Visible = 'off';
            app.motorSpeed4 = uieditfield(app.GridLayout, 'text');
            app.motorSpeed4.Visible = 'off';
            app.motorSpeed5 = uieditfield(app.GridLayout, 'text');
            app.motorSpeed5.Visible = 'off';
            app.MassLabel = uilabel(app.GridLayout);
            app.MassLabel.Visible = 'off';
            app.MassLabel.Text = 'Mass';
            app.motorMass1 = uieditfield(app.GridLayout, 'text');
            app.motorMass1.Editable = 'off';
            app.motorMass1.Visible = 'off';
            app.motorMass2 = uieditfield(app.GridLayout, 'text');
            app.motorMass2.Visible = 'off';
            app.motorMass3 = uieditfield(app.GridLayout, 'text');
            app.motorMass3.Visible = 'off';
            app.motorMass4 = uieditfield(app.GridLayout, 'text');
            app.motorMass4.Visible = 'off';
            app.motorMass5 = uieditfield(app.GridLayout, 'text');
            app.motorMass5.Visible = 'off';
            app.BoxedVolumeLabel = uilabel(app.GridLayout);
            app.BoxedVolumeLabel.Visible = 'off';
            app.BoxedVolumeLabel.Text = 'Boxed Volume';
            app.motorVolume1 = uieditfield(app.GridLayout, 'text');
            app.motorVolume1.Editable = 'off';
            app.motorVolume1.Visible = 'off';
            app.motorVolume2 = uieditfield(app.GridLayout, 'text');
            app.motorVolume2.Visible = 'off';
            app.motorVolume3 = uieditfield(app.GridLayout, 'text');
            app.motorVolume3.Visible = 'off';
            app.motorVolume4 = uieditfield(app.GridLayout, 'text');
            app.motorVolume4.Visible = 'off';
            app.motorVolume5 = uieditfield(app.GridLayout, 'text');
            app.motorVolume5.Visible = 'off';
            app.motorPrice1 = uieditfield(app.GridLayout, 'text');
            app.motorPrice1.Editable = 'off';
            app.motorPrice1.Visible = 'off';
            app.PriceLabel = uilabel(app.GridLayout);
            app.PriceLabel.Visible = 'off';
            app.PriceLabel.Text = 'Price';
            app.motorPrice2 = uieditfield(app.GridLayout, 'text');
            app.motorPrice2.Visible = 'off';
            app.motorPrice3 = uieditfield(app.GridLayout, 'text');
            app.motorPrice3.Visible = 'off';
            app.motorPrice4 = uieditfield(app.GridLayout, 'text');
            app.motorPrice4.Visible = 'off';
            app.motorPrice5 = uieditfield(app.GridLayout, 'text');
            app.motorPrice5.Visible = 'off';
            app.VoltageLabel = uilabel(app.GridLayout);
            app.VoltageLabel.Visible = 'off';
            app.VoltageLabel.Text = 'Voltage';
            app.motorVoltage1 = uieditfield(app.GridLayout, 'numeric');
            app.motorVoltage1.Editable = 'off';
            app.motorVoltage1.Visible = 'off';
            app.motorVoltage2 = uieditfield(app.GridLayout, 'numeric');
            app.motorVoltage2.Editable = 'off';
            app.motorVoltage2.Visible = 'off';
            app.motorVoltage3 = uieditfield(app.GridLayout, 'numeric');
            app.motorVoltage3.Editable = 'off';
            app.motorVoltage3.Visible = 'off';
            app.motorVoltage4 = uieditfield(app.GridLayout, 'numeric');
            app.motorVoltage4.Editable = 'off';
            app.motorVoltage4.Visible = 'off';
            app.motorVoltage5 = uieditfield(app.GridLayout, 'numeric');
            app.motorVoltage5.Editable = 'off';
            app.motorVoltage5.Visible = 'off';
            app.motorTypeLabel = uilabel(app.GridLayout);
            app.motorTypeLabel.Visible = 'off';
            app.motorTypeLabel.Text = 'Type';
            app.motorType1 = uieditfield(app.GridLayout, 'text');
            app.motorType1.Editable = 'off';
            app.motorType1.Visible = 'off';
            app.motorType2 = uieditfield(app.GridLayout, 'text');
            app.motorType2.Editable = 'off';
            app.motorType2.Visible = 'off';
            app.motorType3 = uieditfield(app.GridLayout, 'text');
            app.motorType3.Editable = 'off';
            app.motorType3.Visible = 'off';
            app.motorType4 = uieditfield(app.GridLayout, 'text');
            app.motorType4.Editable = 'off';
            app.motorType4.Visible = 'off';
            app.motorType5 = uieditfield(app.GridLayout, 'text');
            app.motorType5.Editable = 'off';
            app.motorType5.Visible = 'off';
            app.suitableMotorMessage = uilabel(app.GridLayout);
            app.suitableMotorMessage.Visible = 'off';
            app.suitableMotorMessage.Text = 'No suitable motors found. Trying eliminating some constaints, or choosing different values for the performance properties.';
            app.Step3SolutiontotheInverseModelPanel = uipanel(app.HomeTab);
            app.Step3SolutiontotheInverseModelPanel.Title = 'Step 3: Solution to the Inverse Model';
            app.GridLayout3 = uigridlayout(app.Step3SolutiontotheInverseModelPanel);
            app.StaticModelLabel = uilabel(app.GridLayout3);
            app.StaticModelLabel.Text = 'Static Model';
            app.DynamicModelLabel = uilabel(app.GridLayout3);
            app.DynamicModelLabel.Text = 'Dynamic Model';
            app.MotorPropertiesLabel = uilabel(app.GridLayout3);
            app.MotorPropertiesLabel.Text = 'Motor Properties';
            app.ComputationalPerformanceLabel = uilabel(app.GridLayout3);
            app.ComputationalPerformanceLabel.Text = 'Computational Performance';
            app.NoLoadSpeedRPMEditFieldLabel = uilabel(app.GridLayout3);
            app.NoLoadSpeedRPMEditFieldLabel.Text = 'No-Load Speed (RPM)';
            app.NoLoadSpeed = uieditfield(app.GridLayout3, 'numeric');
            app.NoLoadSpeed.Editable = 'off';
            app.StallTorquekgcmEditFieldLabel = uilabel(app.GridLayout3);
            app.StallTorquekgcmEditFieldLabel.Text = 'Stall Torque (kg-cm)';
            app.StallTorque = uieditfield(app.GridLayout3, 'numeric');
            app.StallTorque.Editable = 'off';
            app.SimulatedcontractiontimesLabel = uilabel(app.GridLayout3);
            app.SimulatedcontractiontimesLabel.Text = 'Simulated contraction time (s)';
            app.dt_actual = uieditfield(app.GridLayout3, 'numeric');
            app.dt_actual.Editable = 'off';
            app.SimulatedcontractionrangemLabel = uilabel(app.GridLayout3);
            app.SimulatedcontractionrangemLabel.Text = 'Simulated contraction range (m)';
            app.dX_actual = uieditfield(app.GridLayout3, 'numeric');
            app.dX_actual.Editable = 'off';
            app.PeakpowerrequirementWLabel = uilabel(app.GridLayout3);
            app.PeakpowerrequirementWLabel.Text = 'Peak power requirement (W)';
            app.peakPower = uieditfield(app.GridLayout3, 'numeric');
            app.peakPower.Editable = 'off';
            app.UnloadedStringLengthcmLabel = uilabel(app.GridLayout3);
            app.UnloadedStringLengthcmLabel.Text = 'Unloaded String Length (cm)';
            app.L0 = uieditfield(app.GridLayout3, 'numeric');
            app.L0.Editable = 'off';
            app.LoadedStringLengthcmLabel = uilabel(app.GridLayout3);
            app.LoadedStringLengthcmLabel.Text = 'Loaded String Length (cm)';
            app.X0 = uieditfield(app.GridLayout3, 'numeric');
            app.X0.Editable = 'off';
            app.MaximumRotationsLabel = uilabel(app.GridLayout3);
            app.MaximumRotationsLabel.Text = 'Maximum Rotations';
            app.MaxRotations = uieditfield(app.GridLayout3, 'numeric');
            app.MaxRotations.Editable = 'off';
            app.PeakpowerrequirementWLabel_2 = uilabel(app.GridLayout3);
            app.PeakpowerrequirementWLabel_2.Text = 'Peak torque (mN m)';
            app.PeakTorque = uieditfield(app.GridLayout3, 'numeric');
            app.PeakTorque.Editable = 'off';
            app.StringStiffnessNmEditFieldLabel = uilabel(app.GridLayout3);
            app.StringStiffnessNmEditFieldLabel.Text = 'String Stiffness (N/m)';
            app.K = uieditfield(app.GridLayout3, 'numeric');
            app.K.Editable = 'off';
            app.IterationstoconvergenceEditFieldLabel = uilabel(app.GridLayout3);
            app.IterationstoconvergenceEditFieldLabel.Text = 'Iterations to convergence';
            app.iterations = uieditfield(app.GridLayout3, 'numeric');
            app.TimetoconvergencesEditFieldLabel = uilabel(app.GridLayout3);
            app.TimetoconvergencesEditFieldLabel.Text = 'Time to convergence (s)';
            app.computationTime = uieditfield(app.GridLayout3, 'numeric');
            app.Step2ConstraintsOptionalPanel = uipanel(app.HomeTab);
            app.Step2ConstraintsOptionalPanel.Title = 'Step 2: Constraints (Optional)';
            app.GridLayout4 = uigridlayout(app.Step2ConstraintsOptionalPanel);
            app.MaximumPriceUSDLabel = uilabel(app.GridLayout4);
            app.MaximumPriceUSDLabel.Text = 'Maximum Price (USD)';
            app.maxPrice = uieditfield(app.GridLayout4, 'numeric');
            app.maxPrice.Value = Inf;
            app.minVolume = uieditfield(app.GridLayout4, 'numeric');
            app.minVolume.Limits = [0 Inf];
            app.minVolume.Value = 5000;
            app.maxVolume = uieditfield(app.GridLayout4, 'numeric');
            app.maxVolume.Value = Inf;
            app.IdealVolumemm3Label = uilabel(app.GridLayout4);
            app.IdealVolumemm3Label.Text = 'Ideal Volume (mm^3)';
            app.maxMass = uieditfield(app.GridLayout4, 'numeric');
            app.maxMass.Value = Inf;
            app.MaximumMassgLabel = uilabel(app.GridLayout4);
            app.MaximumMassgLabel.Text = 'Maximum Mass (g)';
            app.nominalVoltageLabel = uilabel(app.GridLayout4);
            app.nominalVoltageLabel.Text = 'Nominal Motor Voltage (V)';
            app.voltage = uidropdown(app.GridLayout4);
            app.voltage.Items = {'6', '12', '24', '36', '48', 'Any'};
            app.voltage.Value = 'Any';
            app.ComputeButton = uibutton(app.GridLayout4, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.MaximumVolumemm3Label = uilabel(app.GridLayout4);
            app.MaximumVolumemm3Label.Text = 'Maximum Volume (mm^3)';
            app.AssumptionsPanel = uipanel(app.HomeTab);
            app.AssumptionsPanel.Title = 'Assumptions';
            app.GridLayout5 = uigridlayout(app.AssumptionsPanel);
            app.MotorViscousFrictionNmsLabel = uilabel(app.GridLayout5);
            app.MotorViscousFrictionNmsLabel.Text = 'Motor Viscous Friction (N*m*s)';
            app.MotorViscousFriction = uieditfield(app.GridLayout5, 'numeric');
            app.MotorViscousFriction.Value = 0.0002;
            app.PayloadViscousFrictionNsmEditFieldLabel = uilabel(app.GridLayout5);
            app.PayloadViscousFrictionNsmEditFieldLabel.Text = 'Payload Viscous Friction (N*s/m)';
            app.PayloadViscousFriction = uieditfield(app.GridLayout5, 'numeric');
            app.PayloadViscousFriction.Value = 10;
            app.RadialStringStiffnessNmEditFieldLabel = uilabel(app.GridLayout5);
            app.RadialStringStiffnessNmEditFieldLabel.Text = 'Radial String Stiffness (N/m)';
            app.RadialStringStiffness = uieditfield(app.GridLayout5, 'numeric');
            app.RadialStringStiffness.Value = 12000;
            app.MotorMomentofInertiakgm2EditFieldLabel = uilabel(app.GridLayout5);
            app.MotorMomentofInertiakgm2EditFieldLabel.Text = 'Motor Moment of Inertia (kg*m^2)';
            app.MotorInertia = uieditfield(app.GridLayout5, 'numeric');
            app.MotorInertia.Value = 1e-06;
            app.PayloadCoulombicFrictionNEditFieldLabel = uilabel(app.GridLayout5);
            app.PayloadCoulombicFrictionNEditFieldLabel.Text = 'Payload Coulombic Friction (N)';
            app.PayloadCoulombicFriction = uieditfield(app.GridLayout5, 'numeric');
            app.PayloadCoulombicFriction.Value = 5;
            app.MotorCoulombicFrictionNmEditFieldLabel = uilabel(app.GridLayout5);
            app.MotorCoulombicFrictionNmEditFieldLabel.Text = 'Motor Coulombic Friction (N*m)';
            app.MotorCoulombicFriction = uieditfield(app.GridLayout5, 'numeric');
            app.MotorCoulombicFriction.Value = 0.002;
            app.MotorTimeConstantsLabel = uilabel(app.GridLayout5);
            app.MotorTimeConstantsLabel.Text = 'Motor Time Constant (s)';
            app.MotorTimeConstant = uieditfield(app.GridLayout5, 'numeric');
            app.MotorTimeConstant.Value = 0.1;
            app.MaximumLinearStrainLabel = uilabel(app.GridLayout5);
            app.MaximumLinearStrainLabel.Text = 'Maximum Linear Strain (%)';
            app.c = uieditfield(app.GridLayout5, 'numeric');
            app.c.Limits = [10 35];
            app.c.Value = 30;
            app.PayloadMasskgEditFieldLabel = uilabel(app.GridLayout5);
            app.PayloadMasskgEditFieldLabel.Text = 'Payload Mass (kg)';
            app.PayloadMass = uieditfield(app.GridLayout5, 'numeric');
            app.PayloadMass.Value = 1;
            app.DesignOptimizationofTwistedStringActuatorsLabel = uilabel(app.UIFigure);
            app.DesignOptimizationofTwistedStringActuatorsLabel.Text = 'Inverse Modeling and Component Selection of Twisted String Actuators';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TSADesign

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end